classdef Dipole_Antennas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DescriptionTextArea            matlab.ui.control.TextArea
        DescriptionTextAreaLabel       matlab.ui.control.Label
        ImpedanceCharacteristicsPanel  matlab.ui.container.Panel
        RadiationEfficiencyButton      matlab.ui.control.Button
        ShowImpedancePlotsButton       matlab.ui.control.Button
        EndFrequencyEditField          matlab.ui.control.NumericEditField
        EndFrequencyEditFieldLabel     matlab.ui.control.Label
        orderDropDown_4                matlab.ui.control.DropDown
        orderDropDown_4Label           matlab.ui.control.Label
        StepFrequencyEditField         matlab.ui.control.NumericEditField
        StepFrequencyEditFieldLabel    matlab.ui.control.Label
        orderDropDown_3                matlab.ui.control.DropDown
        orderDropDown_3Label           matlab.ui.control.Label
        orderDropDown_2                matlab.ui.control.DropDown
        orderDropDown_2Label           matlab.ui.control.Label
        StartFrequencyEditField        matlab.ui.control.NumericEditField
        StartFrequencyEditFieldLabel   matlab.ui.control.Label
        FrequencyCharacteristicsPanel  matlab.ui.container.Panel
        ShowRadiationPatternButton     matlab.ui.control.Button
        EHFieldsButton                 matlab.ui.control.Button
        ShowAzimuthPatternButton       matlab.ui.control.Button
        ShowElevationPatternButton     matlab.ui.control.Button
        FrequencylogscaleSlider        matlab.ui.control.Slider
        FrequencylogscaleSliderLabel   matlab.ui.control.Label
        orderDropDown                  matlab.ui.control.DropDown
        orderDropDownLabel             matlab.ui.control.Label
        FrequencyEditField             matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel        matlab.ui.control.Label
        PhysicalCharacteristicsPanel   matlab.ui.container.Panel
        ConductorDropDown              matlab.ui.control.DropDown
        ConductorDropDownLabel         matlab.ui.control.Label
        WidthinmetersEditField         matlab.ui.control.NumericEditField
        WidthinmetersEditFieldLabel    matlab.ui.control.Label
        LengthinmetersEditField        matlab.ui.control.NumericEditField
        LengthinmetersEditFieldLabel   matlab.ui.control.Label
        LoopRadiusinmetersEditField    matlab.ui.control.NumericEditField
        LoopRadiusinmetersEditFieldLabel  matlab.ui.control.Label
        HeightinmetersEditField        matlab.ui.control.NumericEditField
        HeightinmetersEditFieldLabel   matlab.ui.control.Label
        ThicknessinmetersEditField     matlab.ui.control.NumericEditField
        ThicknessinmetersEditFieldLabel  matlab.ui.control.Label
        RadiusinmetersEditField        matlab.ui.control.NumericEditField
        RadiusinmetersEditFieldLabel   matlab.ui.control.Label
        ArmLength2inmetersEditField    matlab.ui.control.NumericEditField
        ArmLength2inmetersEditFieldLabel  matlab.ui.control.Label
        ArmLength1inmetersEditField    matlab.ui.control.NumericEditField
        ArmLength1inmetersEditFieldLabel  matlab.ui.control.Label
        SpacinginmetersEditField       matlab.ui.control.NumericEditField
        SpacinginmetersEditFieldLabel  matlab.ui.control.Label
        ShowAntennaButton              matlab.ui.control.Button
        FeedOffsetinmetersEditField    matlab.ui.control.NumericEditField
        FeedOffsetinmetersEditFieldLabel  matlab.ui.control.Label
        SelectAntennaTypeButtonGroup   matlab.ui.container.ButtonGroup
        CycloidDipoleAntennaButton     matlab.ui.control.ToggleButton
        MicrostripPatchAntennaButton   matlab.ui.control.ToggleButton
        VeeDipoleAntennaButton         matlab.ui.control.ToggleButton
        CircularLoopAntennaButton      matlab.ui.control.ToggleButton
        FoldedDipoleAntennaButton      matlab.ui.control.ToggleButton
        DipoleAntennaButton            matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        myAntenna = dipole;
        % antennaType
        length = 2;
        height = 0.0060;
        width = 0.1000;
        loopRadius = 0.3100;
        radius = 0.6366;
        thickness = 0.0200;
        feedOffset = 0;
        spacing = 0.0245;
        armLength1 = 1;
        armLength2 = 1;
        startFreq = 50e6;
        order2 = "Hz";
        stepFreq = 1e6;
        order3 = "Hz";
        endFreq = 100e6;
        order4 = "Hz";
        freq = 75e6;
        order = "Hz";
        conductor = "PEC";
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LengthinmetersEditField.Visible = 'on';
            app.LengthinmetersEditFieldLabel.Visible = 'on';
            app.HeightinmetersEditField.Visible = 'off';
            app.HeightinmetersEditFieldLabel.Visible = 'off';
            app.WidthinmetersEditField.Visible = 'on';
            app.WidthinmetersEditFieldLabel.Visible = 'on';
            app.LoopRadiusinmetersEditField.Visible = 'off';
            app.LoopRadiusinmetersEditFieldLabel.Visible = 'off';
            app.SpacinginmetersEditField.Visible = 'off';
            app.SpacinginmetersEditFieldLabel.Visible = 'off';
            app.RadiusinmetersEditField.Visible = 'off';
            app.RadiusinmetersEditFieldLabel.Visible = 'off';
            app.ThicknessinmetersEditField.Visible = 'off';
            app.ThicknessinmetersEditFieldLabel.Visible = 'off';
            app.FeedOffsetinmetersEditField.Visible = 'on';
            app.FeedOffsetinmetersEditFieldLabel.Visible = 'on';
            app.ArmLength1inmetersEditField.Visible = 'off';
            app.ArmLength1inmetersEditFieldLabel.Visible = 'off';
            app.ArmLength2inmetersEditField.Visible = 'off';
            app.ArmLength2inmetersEditFieldLabel.Visible = 'off';
            app.FrequencyEditField.Value = 75;
            app.order = "MHz";
            app.orderDropDown.Value = app.order;
            app.FrequencylogscaleSlider.Value = log10(app.freq);
            app.LengthinmetersEditField.Value = 2;
            app.length = app.LengthinmetersEditField.Value;
            app.WidthinmetersEditField.Value = 0.1000;
            app.width = app.WidthinmetersEditField.Value;
            app.FeedOffsetinmetersEditField.Value = 0;
            app.feedOffset = app.FeedOffsetinmetersEditField.Value;
            app.StartFrequencyEditField.Value = 50;
            app.order2 = "MHz";
            app.orderDropDown_2.Value = "MHz";
            app.StepFrequencyEditField.Value = 1;
            app.order3 = "MHz";
            app.orderDropDown_3.Value = "MHz";
            app.EndFrequencyEditField.Value = 100;
            app.order4 = "MHz";
            app.orderDropDown_4.Value = "MHz";
            app.DescriptionTextArea.Value = "A half-wave dipole antenna consists of two quarter-wavelength conductors placed end to end for a total length of approximately L = λ/2 with feedpoint at center.";
        end

        % Selection changed function: SelectAntennaTypeButtonGroup
        function SelectAntennaTypeButtonGroupSelectionChanged(app, event)
            antennaType = app.SelectAntennaTypeButtonGroup.SelectedObject;
            
            switch antennaType
                case app.DipoleAntennaButton
                    app.myAntenna = dipole();
                    app.LengthinmetersEditField.Visible = 'on';
                    app.LengthinmetersEditFieldLabel.Visible = 'on';
                    app.HeightinmetersEditField.Visible = 'off';
                    app.HeightinmetersEditFieldLabel.Visible = 'off';
                    app.WidthinmetersEditField.Visible = 'on';
                    app.WidthinmetersEditFieldLabel.Visible = 'on';
                    app.LoopRadiusinmetersEditField.Visible = 'off';
                    app.LoopRadiusinmetersEditFieldLabel.Visible = 'off';
                    app.SpacinginmetersEditField.Visible = 'off';
                    app.SpacinginmetersEditFieldLabel.Visible = 'off';
                    app.RadiusinmetersEditField.Visible = 'off';
                    app.RadiusinmetersEditFieldLabel.Visible = 'off';
                    app.ThicknessinmetersEditField.Visible = 'off';
                    app.ThicknessinmetersEditFieldLabel.Visible = 'off';
                    app.FeedOffsetinmetersEditField.Visible = 'on';
                    app.FeedOffsetinmetersEditFieldLabel.Visible = 'on';
                    app.ArmLength1inmetersEditField.Visible = 'off';
                    app.ArmLength1inmetersEditFieldLabel.Visible = 'off';
                    app.ArmLength2inmetersEditField.Visible = 'off';
                    app.ArmLength2inmetersEditFieldLabel.Visible = 'off';
                    app.FrequencyEditField.Value = 75;
                    app.order = "MHz";
                    app.orderDropDown.Value = app.order;
                    app.freq = app.FrequencyEditField.Value*1000000;
                    app.FrequencylogscaleSlider.Value = log10(app.freq);
                    app.LengthinmetersEditField.Value = 2;
                    app.length = app.LengthinmetersEditField.Value;
                    app.WidthinmetersEditField.Value = 0.1000;
                    app.width = app.WidthinmetersEditField.Value;
                    app.FeedOffsetinmetersEditField.Value = 0;
                    app.feedOffset = app.FeedOffsetinmetersEditField.Value;
                    app.DescriptionTextArea.Value = "A half-wave dipole antenna consists of two quarter-wavelength conductors placed end to end for a total length of approximately L = λ/2 with feedpoint at center.";
                case app.FoldedDipoleAntennaButton
                    app.myAntenna = dipoleFolded();
                    app.LengthinmetersEditField.Visible = 'on';
                    app.LengthinmetersEditFieldLabel.Visible = 'on';
                    app.HeightinmetersEditField.Visible = 'off';
                    app.HeightinmetersEditFieldLabel.Visible = 'off';
                    app.WidthinmetersEditField.Visible = 'on';
                    app.WidthinmetersEditFieldLabel.Visible = 'on';
                    app.LoopRadiusinmetersEditField.Visible = 'off';
                    app.LoopRadiusinmetersEditFieldLabel.Visible = 'off';
                    app.SpacinginmetersEditField.Visible = 'on';
                    app.SpacinginmetersEditFieldLabel.Visible = 'on';
                    app.RadiusinmetersEditField.Visible = 'off';
                    app.RadiusinmetersEditFieldLabel.Visible = 'off';
                    app.ThicknessinmetersEditField.Visible = 'off';
                    app.ThicknessinmetersEditFieldLabel.Visible = 'off';
                    app.FeedOffsetinmetersEditField.Visible = 'off';
                    app.FeedOffsetinmetersEditFieldLabel.Visible = 'off';
                    app.ArmLength1inmetersEditField.Visible = 'off';
                    app.ArmLength1inmetersEditFieldLabel.Visible = 'off';
                    app.ArmLength2inmetersEditField.Visible = 'off';
                    app.ArmLength2inmetersEditFieldLabel.Visible = 'off';
                    app.FrequencyEditField.Value = 75;
                    app.order = "MHz";
                    app.orderDropDown.Value = app.order;
                    app.freq = app.FrequencyEditField.Value*1000000;
                    app.FrequencylogscaleSlider.Value = log10(app.freq);
                    app.LengthinmetersEditField.Value = 2;
                    app.length = app.LengthinmetersEditField.Value;                
                    app.WidthinmetersEditField.Value = 0.0040;
                    app.width = app.WidthinmetersEditField.Value;
                    app.SpacinginmetersEditField.Value = 0.0245;
                    app.spacing = app.SpacinginmetersEditField.Value;
                    app.DescriptionTextArea.Value = "A folded dipole is a half-wave dipole with an additional parallel wire connecting its two ends. A folded 'dipole' is, technically, a folded full-wave loop antenna, where the loop has been bent at opposing ends and squashed into two parallel wires in a flat line.";
                case app.VeeDipoleAntennaButton
                    app.myAntenna = dipoleVee();
                    app.LengthinmetersEditField.Visible = 'off';
                    app.LengthinmetersEditFieldLabel.Visible = 'off';
                    app.HeightinmetersEditField.Visible = 'off';
                    app.HeightinmetersEditFieldLabel.Visible = 'off';
                    app.WidthinmetersEditField.Visible = 'on';
                    app.WidthinmetersEditFieldLabel.Visible = 'on';
                    app.LoopRadiusinmetersEditField.Visible = 'off';
                    app.LoopRadiusinmetersEditFieldLabel.Visible = 'off';
                    app.SpacinginmetersEditField.Visible = 'off';
                    app.SpacinginmetersEditFieldLabel.Visible = 'off';
                    app.RadiusinmetersEditField.Visible = 'off';
                    app.RadiusinmetersEditFieldLabel.Visible = 'off';
                    app.ThicknessinmetersEditField.Visible = 'off';
                    app.ThicknessinmetersEditFieldLabel.Visible = 'off';
                    app.FeedOffsetinmetersEditField.Visible = 'off';
                    app.FeedOffsetinmetersEditFieldLabel.Visible = 'off';
                    app.ArmLength1inmetersEditField.Visible = 'on';
                    app.ArmLength1inmetersEditFieldLabel.Visible = 'on';
                    app.ArmLength2inmetersEditField.Visible = 'on';
                    app.ArmLength2inmetersEditFieldLabel.Visible = 'on';
                    app.FrequencyEditField.Value = 75;
                    app.order = "MHz";
                    app.orderDropDown.Value = app.order;
                    app.freq = app.FrequencyEditField.Value*1000000;
                    app.FrequencylogscaleSlider.Value = log10(app.freq);
                    app.ArmLength1inmetersEditField.Value = 1;
                    app.armLength1 = app.ArmLength1inmetersEditField.Value;
                    app.ArmLength2inmetersEditField.Value = 1;
                    app.armLength2 = app.ArmLength2inmetersEditField.Value;
                    app.WidthinmetersEditField.Value = 0.1000;
                    app.width = app.WidthinmetersEditField.Value;
                    app.DescriptionTextArea.Value = "A 'V' (or 'Vee') antenna is a dipole with a bend in the middle so its arms are at an angle instead of co-linear.";
                case app.CircularLoopAntennaButton
                    app.myAntenna = loopCircular();
                    app.LengthinmetersEditField.Visible = 'off';
                    app.LengthinmetersEditFieldLabel.Visible = 'off';
                    app.HeightinmetersEditField.Visible = 'off';
                    app.HeightinmetersEditFieldLabel.Visible = 'off';
                    app.WidthinmetersEditField.Visible = 'off';
                    app.WidthinmetersEditFieldLabel.Visible = 'off';
                    app.LoopRadiusinmetersEditField.Visible = 'off';
                    app.LoopRadiusinmetersEditFieldLabel.Visible = 'off';
                    app.SpacinginmetersEditField.Visible = 'off';
                    app.SpacinginmetersEditFieldLabel.Visible = 'off';
                    app.RadiusinmetersEditField.Visible = 'on';
                    app.RadiusinmetersEditFieldLabel.Visible = 'on';
                    app.ThicknessinmetersEditField.Visible = 'on';
                    app.ThicknessinmetersEditFieldLabel.Visible = 'on';
                    app.FeedOffsetinmetersEditField.Visible = 'off';
                    app.FeedOffsetinmetersEditFieldLabel.Visible = 'off';
                    app.ArmLength1inmetersEditField.Visible = 'off';
                    app.ArmLength1inmetersEditFieldLabel.Visible = 'off';
                    app.ArmLength2inmetersEditField.Visible = 'off';
                    app.ArmLength2inmetersEditFieldLabel.Visible = 'off';
                    app.FrequencyEditField.Value = 75;
                    app.order = "MHz";
                    app.orderDropDown.Value = app.order;
                    app.freq = app.FrequencyEditField.Value*1000000;
                    app.FrequencylogscaleSlider.Value = log10(app.freq);
                    app.RadiusinmetersEditField.Value = 0.6366;
                    app.radius = app.RadiusinmetersEditField.Value;
                    app.ThicknessinmetersEditField.Value = 0.0200;
                    app.thickness = app.ThicknessinmetersEditField.Value;
                    app.DescriptionTextArea.Value = "A Circular Loop antenna is a planer Loop Antenna that is circular in shape with the feed point is on any part of the circumference.";
                case app.MicrostripPatchAntennaButton
                    app.myAntenna = patchMicrostrip();
                    app.LengthinmetersEditField.Visible = 'on';
                    app.LengthinmetersEditFieldLabel.Visible = 'on';
                    app.HeightinmetersEditField.Visible = 'on';
                    app.HeightinmetersEditFieldLabel.Visible = 'on';
                    app.WidthinmetersEditField.Visible = 'on';
                    app.WidthinmetersEditFieldLabel.Visible = 'on';
                    app.LoopRadiusinmetersEditField.Visible = 'off';
                    app.LoopRadiusinmetersEditFieldLabel.Visible = 'off';
                    app.SpacinginmetersEditField.Visible = 'off';
                    app.SpacinginmetersEditFieldLabel.Visible = 'off';
                    app.RadiusinmetersEditField.Visible = 'off';
                    app.RadiusinmetersEditFieldLabel.Visible = 'off';
                    app.ThicknessinmetersEditField.Visible = 'off';
                    app.ThicknessinmetersEditFieldLabel.Visible = 'off';
                    app.FeedOffsetinmetersEditField.Visible = 'off';
                    app.FeedOffsetinmetersEditFieldLabel.Visible = 'off';
                    app.ArmLength1inmetersEditField.Visible = 'off';
                    app.ArmLength1inmetersEditFieldLabel.Visible = 'off';
                    app.ArmLength2inmetersEditField.Visible = 'off';
                    app.ArmLength2inmetersEditFieldLabel.Visible = 'off';
                    app.FrequencyEditField.Value = 1.67;
                    app.order = "GHz";
                    app.orderDropDown.Value = app.order;
                    app.freq = app.FrequencyEditField.Value*1000000000;
                    app.FrequencylogscaleSlider.Value = log10(app.freq);
                    app.LengthinmetersEditField.Value = 0.0750;
                    app.length = app.LengthinmetersEditField.Value;
                    app.WidthinmetersEditField.Value = 0.0375;
                    app.width = app.WidthinmetersEditField.Value;
                    app.HeightinmetersEditField.Value = 0.0060;
                    app.height = app.HeightinmetersEditField.Value;
                    app.DescriptionTextArea.Value = "A Microstrip Patch antenna is a patch antenna that is rectangular in shape with the feed point along the length of the antenna.";
                case app.CycloidDipoleAntennaButton
                    app.myAntenna = dipoleCycloid();
                    app.LengthinmetersEditField.Visible = 'on';
                    app.LengthinmetersEditFieldLabel.Visible = 'on';
                    app.HeightinmetersEditField.Visible = 'off';
                    app.HeightinmetersEditFieldLabel.Visible = 'off';
                    app.WidthinmetersEditField.Visible = 'on';
                    app.WidthinmetersEditFieldLabel.Visible = 'on';
                    app.LoopRadiusinmetersEditField.Visible = 'on';
                    app.LoopRadiusinmetersEditFieldLabel.Visible = 'on';
                    app.SpacinginmetersEditField.Visible = 'off';
                    app.SpacinginmetersEditFieldLabel.Visible = 'off';
                    app.RadiusinmetersEditField.Visible = 'off';
                    app.RadiusinmetersEditFieldLabel.Visible = 'off';
                    app.ThicknessinmetersEditField.Visible = 'off';
                    app.ThicknessinmetersEditFieldLabel.Visible = 'off';
                    app.FeedOffsetinmetersEditField.Visible = 'off';
                    app.FeedOffsetinmetersEditFieldLabel.Visible = 'off';
                    app.ArmLength1inmetersEditField.Visible = 'off';
                    app.ArmLength1inmetersEditFieldLabel.Visible = 'off';
                    app.ArmLength2inmetersEditField.Visible = 'off';
                    app.ArmLength2inmetersEditFieldLabel.Visible = 'off';
                    app.FrequencyEditField.Value = 48;
                    app.order = "MHz";
                    app.orderDropDown.Value = app.order;
                    app.freq = app.FrequencyEditField.Value*1000000;
                    app.FrequencylogscaleSlider.Value = log10(app.freq);
                    app.LengthinmetersEditField.Value = 1.2200;
                    app.length = app.LengthinmetersEditField.Value;
                    app.WidthinmetersEditField.Value = 0.0508;
                    app.width = app.WidthinmetersEditField.Value;
                    app.LoopRadiusinmetersEditField.Value = 0.3100;
                    app.loopRadius = app.LoopRadiusinmetersEditField.Value;
                    app.DescriptionTextArea.Value = "A Cycloid dipole antenna is a dipole antenna with conductor in circular shape as suggested in it's name and the feed point is on the circular loop section.";
            end
        end

        % Value changed function: LengthinmetersEditField
        function LengthinmetersEditFieldValueChanged(app, event)
            app.length = app.LengthinmetersEditField.Value;
        end

        % Value changed function: FeedOffsetinmetersEditField
        function FeedOffsetinmetersEditFieldValueChanged(app, event)
            app.feedOffset = app.FeedOffsetinmetersEditField.Value;
        end

        % Value changed function: SpacinginmetersEditField
        function SpacinginmetersEditFieldValueChanged(app, event)
            app.spacing = app.SpacinginmetersEditField.Value;
        end

        % Value changed function: ArmLength1inmetersEditField
        function ArmLength1inmetersEditFieldValueChanged(app, event)
            app.armLength1 = app.ArmLength1inmetersEditField.Value;
        end

        % Value changed function: ArmLength2inmetersEditField
        function ArmLength2inmetersEditFieldValueChanged(app, event)
            app.armLength2 = app.ArmLength2inmetersEditField.Value;
        end

        % Value changed function: RadiusinmetersEditField
        function RadiusinmetersEditFieldValueChanged(app, event)
            app.radius = app.RadiusinmetersEditField.Value;
        end

        % Value changed function: ThicknessinmetersEditField
        function ThicknessinmetersEditFieldValueChanged(app, event)
            app.thickness = app.ThicknessinmetersEditField.Value;
        end

        % Value changed function: HeightinmetersEditField
        function HeightinmetersEditFieldValueChanged(app, event)
            app.height = app.HeightinmetersEditField.Value;
        end

        % Value changed function: LoopRadiusinmetersEditField
        function LoopRadiusinmetersEditFieldValueChanged(app, event)
            app.loopRadius = app.LoopRadiusinmetersEditField.Value;
        end

        % Value changed function: WidthinmetersEditField
        function WidthinmetersEditFieldValueChanged(app, event)
            app.width = app.WidthinmetersEditField.Value;
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            value = app.FrequencyEditField.Value;
            switch app.order
                case "Hz"
                    app.freq = value;
                case "KHz"
                    app.freq = value*1000;
                case "MHz"
                    app.freq = value*1000000;
                case "GHz"
                    app.freq = value*1000000000;
            end
            app.FrequencylogscaleSlider.Value = log10(app.freq);
        end

        % Value changed function: orderDropDown
        function orderDropDownValueChanged(app, event)
            app.order = app.orderDropDown.Value;
            switch app.order
                case "Hz"
                    app.freq = app.FrequencyEditField.Value;
                case "KHz"
                    app.freq = app.FrequencyEditField.Value*1000;
                case "MHz"
                    app.freq = app.FrequencyEditField.Value*1000000;
                case "GHz"
                    app.freq = app.FrequencyEditField.Value*1000000000;
            end
            app.FrequencylogscaleSlider.Value = log10(app.freq);
        end

        % Value changed function: FrequencylogscaleSlider
        function FrequencylogscaleSliderValueChanged(app, event)
            value = app.FrequencylogscaleSlider.Value;
            if(value < 3)
                app.FrequencyEditField.Value = 10^value;
                app.orderDropDown.Value = "Hz";
            elseif((value >= 3) && (value < 6))
                app.FrequencyEditField.Value = 10^(value-3);
                app.orderDropDown.Value = "KHz";
            elseif((value >= 6) && (value < 9))
                app.FrequencyEditField.Value = 10^(value-6);
                app.orderDropDown.Value = "MHz";
            elseif((value >= 9) && (value < 12))
                app.FrequencyEditField.Value = 10^(value-9);
                app.orderDropDown.Value = "GHz";
            else
                disp("NOT POSSIBLE");
            end
            app.freq = 10^value;
        end

        % Value changed function: StartFrequencyEditField
        function StartFrequencyEditFieldValueChanged(app, event)
            value = app.StartFrequencyEditField.Value;
            switch app.order2
                case "Hz"
                    app.startFreq = value;
                case "KHz"
                    app.startFreq = value*1000;
                case "MHz"
                    app.startFreq = value*1000000;
            end
        end

        % Value changed function: orderDropDown_2
        function orderDropDown_2ValueChanged(app, event)
            app.order2 = app.orderDropDown_2.Value;
            switch app.order2
                case "Hz"
                    app.startFreq = app.StartFrequencyEditField.Value;
                case "KHz"
                    app.startFreq = app.StartFrequencyEditField.Value*1000;
                case "MHz"
                    app.startFreq = app.StartFrequencyEditField.Value*1000000;
            end
        end

        % Value changed function: StepFrequencyEditField
        function StepFrequencyEditFieldValueChanged(app, event)
            value = app.StepFrequencyEditField.Value;
            switch app.order3
                case "Hz"
                    app.stepFreq = value;
                case "KHz"
                    app.stepFreq = value*1000;
                case "MHz"
                    app.stepFreq = value*1000000;
            end
        end

        % Value changed function: orderDropDown_3
        function orderDropDown_3ValueChanged(app, event)
            app.order3 = app.orderDropDown_3.Value;
            switch app.order3
                case "Hz"
                    app.stepFreq = app.StepFrequencyEditField.Value;
                case "KHz"
                    app.stepFreq = app.StepFrequencyEditField.Value*1000;
                case "MHz"
                    app.stepFreq = app.StepFrequencyEditField.Value*1000000;
            end
        end

        % Value changed function: EndFrequencyEditField
        function EndFrequencyEditFieldValueChanged(app, event)
            value = app.EndFrequencyEditField.Value;
            switch app.order4
                case "Hz"
                    app.endFreq = value;
                case "KHz"
                    app.endFreq = value*1000;
                case "MHz"
                    app.endFreq = value*1000000;
            end
        end

        % Value changed function: orderDropDown_4
        function orderDropDown_4ValueChanged(app, event)
            app.order4 = app.orderDropDown_4.Value;
            switch app.order4
                case "Hz"
                    app.endFreq = app.EndFrequencyEditField.Value;
                case "KHz"
                    app.endFreq = app.EndFrequencyEditField.Value*1000;
                case "MHz"
                    app.endFreq = app.EndFrequencyEditField.Value*1000000;
            end
        end

        % Button pushed function: ShowAntennaButton
        function ShowAntennaButtonPushed(app, event)
            switch app.SelectAntennaTypeButtonGroup.SelectedObject
                case app.DipoleAntennaButton
                    app.myAntenna = dipole('Length', app.length, 'Width', app.width, 'FeedOffset', app.feedOffset, 'Conductor', metal(app.conductor));
                case app.FoldedDipoleAntennaButton
                    app.myAntenna = dipoleFolded("Length", app.length, "Width", app.width, "Spacing", app.spacing, 'Conductor', metal(app.conductor));
                case app.VeeDipoleAntennaButton
                    app.myAntenna = dipoleVee("ArmLength", [app.armLength1, app.armLength2], "Width", app.width, 'Conductor', metal(app.conductor));
                case app.CircularLoopAntennaButton
                    app.myAntenna = loopCircular("Radius", app.radius, "Thickness", app.thickness, 'Conductor', metal(app.conductor));
                case app.MicrostripPatchAntennaButton
                    app.myAntenna = patchMicrostrip("Length", app.length, "Width", app.width, "Height", app.height, 'Conductor', metal(app.conductor));
                case app.CycloidDipoleAntennaButton
                    app.myAntenna = dipoleCycloid("Length", app.length, "Width", app.width, "LoopRadius", app.loopRadius, 'Conductor', metal(app.conductor));         
            end
            figure('Name',app.SelectAntennaTypeButtonGroup.SelectedObject.Text, 'NumberTitle','off');
            show(app.myAntenna);
        end

        % Button pushed function: ShowRadiationPatternButton
        function ShowRadiationPatternButtonPushed(app, event)
            switch app.SelectAntennaTypeButtonGroup.SelectedObject
                case app.DipoleAntennaButton
                    app.myAntenna = dipole('Length', app.length, 'Width', app.width, 'FeedOffset', app.feedOffset, 'Conductor', metal(app.conductor));
                case app.FoldedDipoleAntennaButton
                    app.myAntenna = dipoleFolded("Length", app.length, "Width", app.width, "Spacing", app.spacing, 'Conductor', metal(app.conductor));
                case app.VeeDipoleAntennaButton
                    app.myAntenna = dipoleVee("ArmLength", [app.armLength1, app.armLength2], "Width", app.width, 'Conductor', metal(app.conductor));
                case app.CircularLoopAntennaButton
                    app.myAntenna = loopCircular("Radius", app.radius, "Thickness", app.thickness, 'Conductor', metal(app.conductor));
                case app.MicrostripPatchAntennaButton
                    app.myAntenna = patchMicrostrip("Length", app.length, "Width", app.width, "Height", app.height, 'Conductor', metal(app.conductor));
                case app.CycloidDipoleAntennaButton
                    app.myAntenna = dipoleCycloid("Length", app.length, "Width", app.width, "LoopRadius", app.loopRadius, 'Conductor', metal(app.conductor));         
            end
            figure('Name',append("Radiation Pattern : ",app.SelectAntennaTypeButtonGroup.SelectedObject.Text), 'NumberTitle','off');
            pattern(app.myAntenna, app.freq);
        end

        % Button pushed function: ShowAzimuthPatternButton
        function ShowAzimuthPatternButtonPushed(app, event)
            switch app.SelectAntennaTypeButtonGroup.SelectedObject
                case app.DipoleAntennaButton
                    app.myAntenna = dipole('Length', app.length, 'Width', app.width, 'FeedOffset', app.feedOffset, 'Conductor', metal(app.conductor));
                case app.FoldedDipoleAntennaButton
                    app.myAntenna = dipoleFolded("Length", app.length, "Width", app.width, "Spacing", app.spacing, 'Conductor', metal(app.conductor));
                case app.VeeDipoleAntennaButton
                    app.myAntenna = dipoleVee("ArmLength", [app.armLength1, app.armLength2], "Width", app.width, 'Conductor', metal(app.conductor));
                case app.CircularLoopAntennaButton
                    app.myAntenna = loopCircular("Radius", app.radius, "Thickness", app.thickness, 'Conductor', metal(app.conductor));
                case app.MicrostripPatchAntennaButton
                    app.myAntenna = patchMicrostrip("Length", app.length, "Width", app.width, "Height", app.height, 'Conductor', metal(app.conductor));
                case app.CycloidDipoleAntennaButton
                    app.myAntenna = dipoleCycloid("Length", app.length, "Width", app.width, "LoopRadius", app.loopRadius, 'Conductor', metal(app.conductor));         
            end
            figure('Name',append("Azimuth Pattern : ",app.SelectAntennaTypeButtonGroup.SelectedObject.Text), 'NumberTitle','off');
            patternAzimuth(app.myAntenna, app.freq);
        end

        % Button pushed function: ShowElevationPatternButton
        function ShowElevationPatternButtonPushed(app, event)
            switch app.SelectAntennaTypeButtonGroup.SelectedObject
                case app.DipoleAntennaButton
                    app.myAntenna = dipole('Length', app.length, 'Width', app.width, 'FeedOffset', app.feedOffset, 'Conductor', metal(app.conductor));
                case app.FoldedDipoleAntennaButton
                    app.myAntenna = dipoleFolded("Length", app.length, "Width", app.width, "Spacing", app.spacing, 'Conductor', metal(app.conductor));
                case app.VeeDipoleAntennaButton
                    app.myAntenna = dipoleVee("ArmLength", [app.armLength1, app.armLength2], "Width", app.width, 'Conductor', metal(app.conductor));
                case app.CircularLoopAntennaButton
                    app.myAntenna = loopCircular("Radius", app.radius, "Thickness", app.thickness, 'Conductor', metal(app.conductor));
                case app.MicrostripPatchAntennaButton
                    app.myAntenna = patchMicrostrip("Length", app.length, "Width", app.width, "Height", app.height, 'Conductor', metal(app.conductor));
                case app.CycloidDipoleAntennaButton
                    app.myAntenna = dipoleCycloid("Length", app.length, "Width", app.width, "LoopRadius", app.loopRadius, 'Conductor', metal(app.conductor));         
            end
            figure('Name',append("Elevation Pattern : ",app.SelectAntennaTypeButtonGroup.SelectedObject.Text), 'NumberTitle','off');
            patternElevation(app.myAntenna, app.freq);
        end

        % Button pushed function: EHFieldsButton
        function EHFieldsButtonPushed(app, event)
            switch app.SelectAntennaTypeButtonGroup.SelectedObject
                case app.DipoleAntennaButton
                    app.myAntenna = dipole('Length', app.length, 'Width', app.width, 'FeedOffset', app.feedOffset, 'Conductor', metal(app.conductor));
                case app.FoldedDipoleAntennaButton
                    app.myAntenna = dipoleFolded("Length", app.length, "Width", app.width, "Spacing", app.spacing, 'Conductor', metal(app.conductor));
                case app.VeeDipoleAntennaButton
                    app.myAntenna = dipoleVee("ArmLength", [app.armLength1, app.armLength2], "Width", app.width, 'Conductor', metal(app.conductor));
                case app.CircularLoopAntennaButton
                    app.myAntenna = loopCircular("Radius", app.radius, "Thickness", app.thickness, 'Conductor', metal(app.conductor));
                case app.MicrostripPatchAntennaButton
                    app.myAntenna = patchMicrostrip("Length", app.length, "Width", app.width, "Height", app.height, 'Conductor', metal(app.conductor));
                case app.CycloidDipoleAntennaButton
                    app.myAntenna = dipoleCycloid("Length", app.length, "Width", app.width, "LoopRadius", app.loopRadius, 'Conductor', metal(app.conductor));         
            end
            figure('Name',append("E & H Fields : ",app.SelectAntennaTypeButtonGroup.SelectedObject.Text), 'NumberTitle','off');
            EHfields(app.myAntenna, app.freq);
        end

        % Button pushed function: ShowImpedancePlotsButton
        function ShowImpedancePlotsButtonPushed(app, event)
            switch app.SelectAntennaTypeButtonGroup.SelectedObject
                case app.DipoleAntennaButton
                    app.myAntenna = dipole('Length', app.length, 'Width', app.width, 'FeedOffset', app.feedOffset, 'Conductor', metal(app.conductor));
                case app.FoldedDipoleAntennaButton
                    app.myAntenna = dipoleFolded("Length", app.length, "Width", app.width, "Spacing", app.spacing, 'Conductor', metal(app.conductor));
                case app.VeeDipoleAntennaButton
                    app.myAntenna = dipoleVee("ArmLength", [app.armLength1, app.armLength2], "Width", app.width, 'Conductor', metal(app.conductor));
                case app.CircularLoopAntennaButton
                    app.myAntenna = loopCircular("Radius", app.radius, "Thickness", app.thickness, 'Conductor', metal(app.conductor));
                case app.MicrostripPatchAntennaButton
                    app.myAntenna = patchMicrostrip("Length", app.length, "Width", app.width, "Height", app.height, 'Conductor', metal(app.conductor));
                case app.CycloidDipoleAntennaButton
                    app.myAntenna = dipoleCycloid("Length", app.length, "Width", app.width, "LoopRadius", app.loopRadius, 'Conductor', metal(app.conductor));         
            end
            figure('Name',append("Impedance Plots : ",app.SelectAntennaTypeButtonGroup.SelectedObject.Text), 'NumberTitle','off');
            impedance(app.myAntenna, app.startFreq:app.stepFreq:app.endFreq);
        end

        % Button pushed function: RadiationEfficiencyButton
        function RadiationEfficiencyButtonPushed(app, event)
            switch app.SelectAntennaTypeButtonGroup.SelectedObject
                case app.DipoleAntennaButton
                    app.myAntenna = dipole('Length', app.length, 'Width', app.width, 'FeedOffset', app.feedOffset, 'Conductor', metal(app.conductor));
                case app.FoldedDipoleAntennaButton
                    app.myAntenna = dipoleFolded("Length", app.length, "Width", app.width, "Spacing", app.spacing, 'Conductor', metal(app.conductor));
                case app.VeeDipoleAntennaButton
                    app.myAntenna = dipoleVee("ArmLength", [app.armLength1, app.armLength2], "Width", app.width, 'Conductor', metal(app.conductor));
                case app.CircularLoopAntennaButton
                    app.myAntenna = loopCircular("Radius", app.radius, "Thickness", app.thickness, 'Conductor', metal(app.conductor));
                case app.MicrostripPatchAntennaButton
                    app.myAntenna = patchMicrostrip("Length", app.length, "Width", app.width, "Height", app.height, 'Conductor', metal(app.conductor));
                case app.CycloidDipoleAntennaButton
                    app.myAntenna = dipoleCycloid("Length", app.length, "Width", app.width, "LoopRadius", app.loopRadius, 'Conductor', metal(app.conductor));         
            end
            figure('Name',append("Radiation Efficiency Plot : ",app.SelectAntennaTypeButtonGroup.SelectedObject.Text), 'NumberTitle','off');
            efficiency(app.myAntenna, app.startFreq:app.stepFreq:app.endFreq);
        end

        % Value changed function: ConductorDropDown
        function ConductorDropDownValueChanged(app, event)
            app.conductor = app.ConductorDropDown.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectAntennaTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectAntennaTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectAntennaTypeButtonGroupSelectionChanged, true);
            app.SelectAntennaTypeButtonGroup.Title = 'Select Antenna Type';
            app.DipoleAntennaButton = uitogglebutton(app.SelectAntennaTypeButtonGroup);
            app.DipoleAntennaButton.Text = 'Dipole Antenna';
            app.DipoleAntennaButton.Value = true;
            app.FoldedDipoleAntennaButton = uitogglebutton(app.SelectAntennaTypeButtonGroup);
            app.FoldedDipoleAntennaButton.Text = 'Folded Dipole Antenna';
            app.CircularLoopAntennaButton = uitogglebutton(app.SelectAntennaTypeButtonGroup);
            app.CircularLoopAntennaButton.Text = 'Circular Loop Antenna';
            app.VeeDipoleAntennaButton = uitogglebutton(app.SelectAntennaTypeButtonGroup);
            app.VeeDipoleAntennaButton.Text = 'Vee Dipole Antenna';
            app.MicrostripPatchAntennaButton = uitogglebutton(app.SelectAntennaTypeButtonGroup);
            app.MicrostripPatchAntennaButton.Text = 'Microstrip Patch Antenna';
            app.CycloidDipoleAntennaButton = uitogglebutton(app.SelectAntennaTypeButtonGroup);
            app.CycloidDipoleAntennaButton.Text = 'Cycloid Dipole Antenna';
            app.PhysicalCharacteristicsPanel = uipanel(app.UIFigure);
            app.PhysicalCharacteristicsPanel.Title = 'Physical Characteristics';
            app.FeedOffsetinmetersEditFieldLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.FeedOffsetinmetersEditFieldLabel.Text = 'Feed Offset (in meters)';
            app.FeedOffsetinmetersEditField = uieditfield(app.PhysicalCharacteristicsPanel, 'numeric');
            app.FeedOffsetinmetersEditField.ValueChangedFcn = createCallbackFcn(app, @FeedOffsetinmetersEditFieldValueChanged, true);
            app.ShowAntennaButton = uibutton(app.PhysicalCharacteristicsPanel, 'push');
            app.ShowAntennaButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAntennaButtonPushed, true);
            app.ShowAntennaButton.Text = 'Show Antenna';
            app.SpacinginmetersEditFieldLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.SpacinginmetersEditFieldLabel.Text = 'Spacing (in meters)';
            app.SpacinginmetersEditField = uieditfield(app.PhysicalCharacteristicsPanel, 'numeric');
            app.SpacinginmetersEditField.ValueChangedFcn = createCallbackFcn(app, @SpacinginmetersEditFieldValueChanged, true);
            app.ArmLength1inmetersEditFieldLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.ArmLength1inmetersEditFieldLabel.Text = 'Arm Length 1 (in meters)';
            app.ArmLength1inmetersEditField = uieditfield(app.PhysicalCharacteristicsPanel, 'numeric');
            app.ArmLength1inmetersEditField.ValueChangedFcn = createCallbackFcn(app, @ArmLength1inmetersEditFieldValueChanged, true);
            app.ArmLength2inmetersEditFieldLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.ArmLength2inmetersEditFieldLabel.Text = 'Arm Length 2 (in meters)';
            app.ArmLength2inmetersEditField = uieditfield(app.PhysicalCharacteristicsPanel, 'numeric');
            app.ArmLength2inmetersEditField.ValueChangedFcn = createCallbackFcn(app, @ArmLength2inmetersEditFieldValueChanged, true);
            app.RadiusinmetersEditFieldLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.RadiusinmetersEditFieldLabel.Text = 'Radius (in meters)';
            app.RadiusinmetersEditField = uieditfield(app.PhysicalCharacteristicsPanel, 'numeric');
            app.RadiusinmetersEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusinmetersEditFieldValueChanged, true);
            app.ThicknessinmetersEditFieldLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.ThicknessinmetersEditFieldLabel.Text = 'Thickness (in meters)';
            app.ThicknessinmetersEditField = uieditfield(app.PhysicalCharacteristicsPanel, 'numeric');
            app.ThicknessinmetersEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessinmetersEditFieldValueChanged, true);
            app.HeightinmetersEditFieldLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.HeightinmetersEditFieldLabel.Text = 'Height (in meters)';
            app.HeightinmetersEditField = uieditfield(app.PhysicalCharacteristicsPanel, 'numeric');
            app.HeightinmetersEditField.ValueChangedFcn = createCallbackFcn(app, @HeightinmetersEditFieldValueChanged, true);
            app.LoopRadiusinmetersEditFieldLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.LoopRadiusinmetersEditFieldLabel.Text = 'Loop Radius (in meters)';
            app.LoopRadiusinmetersEditField = uieditfield(app.PhysicalCharacteristicsPanel, 'numeric');
            app.LoopRadiusinmetersEditField.ValueChangedFcn = createCallbackFcn(app, @LoopRadiusinmetersEditFieldValueChanged, true);
            app.LengthinmetersEditFieldLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.LengthinmetersEditFieldLabel.Text = 'Length (in meters)';
            app.LengthinmetersEditField = uieditfield(app.PhysicalCharacteristicsPanel, 'numeric');
            app.LengthinmetersEditField.ValueChangedFcn = createCallbackFcn(app, @LengthinmetersEditFieldValueChanged, true);
            app.WidthinmetersEditFieldLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.WidthinmetersEditFieldLabel.Text = 'Width (in meters)';
            app.WidthinmetersEditField = uieditfield(app.PhysicalCharacteristicsPanel, 'numeric');
            app.WidthinmetersEditField.ValueChangedFcn = createCallbackFcn(app, @WidthinmetersEditFieldValueChanged, true);
            app.ConductorDropDownLabel = uilabel(app.PhysicalCharacteristicsPanel);
            app.ConductorDropDownLabel.Text = 'Conductor';
            app.ConductorDropDown = uidropdown(app.PhysicalCharacteristicsPanel);
            app.ConductorDropDown.Items = {'PEC', 'Copper', 'Aluminium', 'Lead'};
            app.ConductorDropDown.ValueChangedFcn = createCallbackFcn(app, @ConductorDropDownValueChanged, true);
            app.ConductorDropDown.Value = 'PEC';
            app.FrequencyCharacteristicsPanel = uipanel(app.UIFigure);
            app.FrequencyCharacteristicsPanel.Title = 'Frequency Characteristics';
            app.FrequencyEditFieldLabel = uilabel(app.FrequencyCharacteristicsPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.FrequencyCharacteristicsPanel, 'numeric');
            app.FrequencyEditField.Limits = [1 999];
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.FrequencyEditField.Value = 1;
            app.orderDropDownLabel = uilabel(app.FrequencyCharacteristicsPanel);
            app.orderDropDownLabel.Text = 'order';
            app.orderDropDown = uidropdown(app.FrequencyCharacteristicsPanel);
            app.orderDropDown.Items = {'Hz', 'KHz', 'MHz', 'GHz'};
            app.orderDropDown.ValueChangedFcn = createCallbackFcn(app, @orderDropDownValueChanged, true);
            app.orderDropDown.Value = 'Hz';
            app.FrequencylogscaleSliderLabel = uilabel(app.FrequencyCharacteristicsPanel);
            app.FrequencylogscaleSliderLabel.Text = 'Frequency (log scale)';
            app.FrequencylogscaleSlider = uislider(app.FrequencyCharacteristicsPanel);
            app.FrequencylogscaleSlider.Limits = [0 12];
            app.FrequencylogscaleSlider.ValueChangedFcn = createCallbackFcn(app, @FrequencylogscaleSliderValueChanged, true);
            app.ShowElevationPatternButton = uibutton(app.FrequencyCharacteristicsPanel, 'push');
            app.ShowElevationPatternButton.ButtonPushedFcn = createCallbackFcn(app, @ShowElevationPatternButtonPushed, true);
            app.ShowElevationPatternButton.Text = 'Show Elevation Pattern';
            app.ShowAzimuthPatternButton = uibutton(app.FrequencyCharacteristicsPanel, 'push');
            app.ShowAzimuthPatternButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAzimuthPatternButtonPushed, true);
            app.ShowAzimuthPatternButton.Text = 'Show Azimuth Pattern';
            app.EHFieldsButton = uibutton(app.FrequencyCharacteristicsPanel, 'push');
            app.EHFieldsButton.ButtonPushedFcn = createCallbackFcn(app, @EHFieldsButtonPushed, true);
            app.EHFieldsButton.Text = 'E & H Fields';
            app.ShowRadiationPatternButton = uibutton(app.FrequencyCharacteristicsPanel, 'push');
            app.ShowRadiationPatternButton.ButtonPushedFcn = createCallbackFcn(app, @ShowRadiationPatternButtonPushed, true);
            app.ShowRadiationPatternButton.Text = 'Show Radiation Pattern';
            app.ImpedanceCharacteristicsPanel = uipanel(app.UIFigure);
            app.ImpedanceCharacteristicsPanel.Title = 'Impedance Characteristics';
            app.StartFrequencyEditFieldLabel = uilabel(app.ImpedanceCharacteristicsPanel);
            app.StartFrequencyEditFieldLabel.Text = 'Start Frequency';
            app.StartFrequencyEditField = uieditfield(app.ImpedanceCharacteristicsPanel, 'numeric');
            app.StartFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @StartFrequencyEditFieldValueChanged, true);
            app.orderDropDown_2Label = uilabel(app.ImpedanceCharacteristicsPanel);
            app.orderDropDown_2Label.Text = 'order';
            app.orderDropDown_2 = uidropdown(app.ImpedanceCharacteristicsPanel);
            app.orderDropDown_2.Items = {'Hz', 'KHz', 'MHz'};
            app.orderDropDown_2.ValueChangedFcn = createCallbackFcn(app, @orderDropDown_2ValueChanged, true);
            app.orderDropDown_2.Value = 'Hz';
            app.orderDropDown_3Label = uilabel(app.ImpedanceCharacteristicsPanel);
            app.orderDropDown_3Label.Text = 'order';
            app.orderDropDown_3 = uidropdown(app.ImpedanceCharacteristicsPanel);
            app.orderDropDown_3.Items = {'Hz', 'KHz', 'MHz'};
            app.orderDropDown_3.ValueChangedFcn = createCallbackFcn(app, @orderDropDown_3ValueChanged, true);
            app.orderDropDown_3.Value = 'Hz';
            app.StepFrequencyEditFieldLabel = uilabel(app.ImpedanceCharacteristicsPanel);
            app.StepFrequencyEditFieldLabel.Text = 'Step Frequency';
            app.StepFrequencyEditField = uieditfield(app.ImpedanceCharacteristicsPanel, 'numeric');
            app.StepFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @StepFrequencyEditFieldValueChanged, true);
            app.orderDropDown_4Label = uilabel(app.ImpedanceCharacteristicsPanel);
            app.orderDropDown_4Label.Text = 'order';
            app.orderDropDown_4 = uidropdown(app.ImpedanceCharacteristicsPanel);
            app.orderDropDown_4.Items = {'Hz', 'KHz', 'MHz'};
            app.orderDropDown_4.ValueChangedFcn = createCallbackFcn(app, @orderDropDown_4ValueChanged, true);
            app.orderDropDown_4.Value = 'Hz';
            app.EndFrequencyEditFieldLabel = uilabel(app.ImpedanceCharacteristicsPanel);
            app.EndFrequencyEditFieldLabel.Text = 'End Frequency';
            app.EndFrequencyEditField = uieditfield(app.ImpedanceCharacteristicsPanel, 'numeric');
            app.EndFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @EndFrequencyEditFieldValueChanged, true);
            app.ShowImpedancePlotsButton = uibutton(app.ImpedanceCharacteristicsPanel, 'push');
            app.ShowImpedancePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowImpedancePlotsButtonPushed, true);
            app.ShowImpedancePlotsButton.Text = 'Show Impedance Plots';
            app.RadiationEfficiencyButton = uibutton(app.ImpedanceCharacteristicsPanel, 'push');
            app.RadiationEfficiencyButton.ButtonPushedFcn = createCallbackFcn(app, @RadiationEfficiencyButtonPushed, true);
            app.RadiationEfficiencyButton.Text = 'Radiation Efficiency';
            app.DescriptionTextAreaLabel = uilabel(app.UIFigure);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.UIFigure);
            app.DescriptionTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dipole_Antennas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end